classdef Lab6_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WindChill35706T357V016043TV016Label  matlab.ui.control.Label
        TSlider                      matlab.ui.control.Slider
        V000Slider                   matlab.ui.control.Slider
        TtemperatureVwindspeedLabel  matlab.ui.control.Label
        WC357060357001604300016357Label  matlab.ui.control.Label
        TValueLabel                  matlab.ui.control.Label
        TLabel                       matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        VValueLabel                  matlab.ui.control.Label
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TSlider, V000Slider
        function TSliderValueChanged(app, event)
            temp = app.TSlider.Value;
            speed = app.V000Slider.Value;
            
            app.TValueLabel.Text = sprintf('%.2f', temp);
            app.VValueLabel.Text = sprintf('%.2f', speed);
            
            value = 35.7 + 0.6*temp - 35.7*speed^0.16 + 0.43*temp*speed^0.16;
            
            text = sprintf('WC = 35.7 + 0.6*(%.2f) - 35.7*(%.2f)^0.16 + 0.43*(%.2f)*(%.2f)^0.16 = %.2f', temp, speed, temp, speed, value);
            app.WC357060357001604300016357Label.Text = text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WindChill35706T357V016043TV016Label = uilabel(app.UIFigure);
            app.WindChill35706T357V016043TV016Label.Text = 'Wind Chill = 35.7 + 0.6T - 35.7 (V^0.16) + 0.43T (V^0.16)';
            app.TSlider = uislider(app.UIFigure);
            app.TSlider.ValueChangedFcn = createCallbackFcn(app, @TSliderValueChanged, true);
            app.V000Slider = uislider(app.UIFigure);
            app.V000Slider.ValueChangedFcn = createCallbackFcn(app, @TSliderValueChanged, true);
            app.TtemperatureVwindspeedLabel = uilabel(app.UIFigure);
            app.TtemperatureVwindspeedLabel.Text = 'T = temperature, V = wind speed';
            app.WC357060357001604300016357Label = uilabel(app.UIFigure);
            app.WC357060357001604300016357Label.Text = 'WC = 35.7 + 0.6*(0) - 35.7*(0)^0.16 + 0.43*(0)*(0)^0.16 = 35.7';
            app.TValueLabel = uilabel(app.UIFigure);
            app.TValueLabel.Text = '0.00';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.VValueLabel = uilabel(app.UIFigure);
            app.VValueLabel.Text = '0.00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab6_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end